classdef DataAnlysis_R1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MYFUNC                       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ImportTab                    matlab.ui.container.Tab
        Button_2                     matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        MYTEMPFUNCLabel              matlab.ui.control.Label
        Button_3                     matlab.ui.control.Button
        Label_3                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        egtimeencodercapheightEditField  matlab.ui.control.EditField
        egtimeencodercapheightLabel  matlab.ui.control.Label
        Button_5                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        Button_9                     matlab.ui.control.Button
        Button_11                    matlab.ui.control.Button
        Label_10                     matlab.ui.control.Label
        Data1Tab                     matlab.ui.container.Tab
        Button_7                     matlab.ui.control.Button
        Label_9                      matlab.ui.control.Label
        Button_8                     matlab.ui.control.Button
        AxisLabel                    matlab.ui.control.Label
        HandleFunctionLabel          matlab.ui.control.Label
        XTextArea_2Label             matlab.ui.control.Label
        XTextArea_2                  matlab.ui.control.TextArea
        Y1TextAreaLabel              matlab.ui.control.Label
        Y1TextArea                   matlab.ui.control.TextArea
        Y2TextAreaLabel              matlab.ui.control.Label
        Y2TextArea                   matlab.ui.control.TextArea
        Y3TextAreaLabel              matlab.ui.control.Label
        Y3TextArea                   matlab.ui.control.TextArea
        Y4TextAreaLabel              matlab.ui.control.Label
        Y4TextArea                   matlab.ui.control.TextArea
        Y5TextAreaLabel              matlab.ui.control.Label
        Y5TextArea                   matlab.ui.control.TextArea
        Y6TextAreaLabel              matlab.ui.control.Label
        Y6TextArea                   matlab.ui.control.TextArea
        Y7TextAreaLabel              matlab.ui.control.Label
        Y7TextArea                   matlab.ui.control.TextArea
        SetToPlotCheckBox            matlab.ui.control.CheckBox
        SetToPlotCheckBox_2          matlab.ui.control.CheckBox
        SetToPlotCheckBox_3          matlab.ui.control.CheckBox
        SetToPlotCheckBox_4          matlab.ui.control.CheckBox
        SetToPlotCheckBox_5          matlab.ui.control.CheckBox
        SetToPlotCheckBox_6          matlab.ui.control.CheckBox
        SetToPlotCheckBox_7          matlab.ui.control.CheckBox
        SetToPlotCheckBox_8          matlab.ui.control.CheckBox
        NothingLabel                 matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        File char % 获取文件
        FileTable = strings(0);
        DataPath char % 数据文件路径
        tempTxtFilePath char % 特定文件
        ColName; % 列名字数组
        DataSize_Row ;% 数据大小
        DataSize_Col ;
        Timer_01 = timer('StartDelay',1,'ExecutionMode','fixedRate','Period',1); % Description
        FigureModel char; % 绘图方式
        DrawDownItems; % 下拉选项
    end
    
    properties (Access = public)
        Tab3_Origin = zeros(0); % Description
    end
    
    methods (Access = private)
        % 更新tab-3显示信息
        function UpdateTempDataTabInfo(app,info,~)
            if ischar(info)
                app.NothingLabel.Text = (info);
            end
        end
        
        function UpdateTime(app)
            app.Label_8.Text = datestr(datetime('now'),'HH:MM:SS');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            disp('Data Anlysis Tool Starting...')
            app.File = '' ;
            app.DataPath = '' ;
            app.FileTable = strings(0);
            app.tempTxtFilePath = '';
            app.DataSize_Row =  0;% 数据大小
            app.DataSize_Col = 0;
            
            % 注册时间函数
            app.Timer_01.TimerFcn = @(~,~)UpdateTime(app);
            start(app.Timer_01);
        end

        % Callback function
        function ButtonPushed(app, event)
            fileFilter = {'*.txt'};
            [file,path] = uigetfile(fileFilter,'MultiSelect','on');
            app.File = file;
            app.DataPath = path;
            app.FileTable = strings(0);
            if isequal(file,0)
                return;
            elseif ~iscell(file)
                app.FileTable(1) = string(file);
            else
                for i = 1:1:length(file)
                    app.FileTable(i) = string(file(i));
                end
            end
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            % double click
            app.MYFUNC.SelectionType = 'open';
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.XDropDown.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 导入数据
            try
                temp = load(app.EditField.Value);
                app.Tab3_Origin = temp;
                info_temp = strcat(app.tempTxtFilePath,'  数据导入成功');
                UpdateTempDataTabInfo(app,info_temp);
            catch EM
                %                 info_temp = strcat(app.tempTxtFilePath,'  数据导入失败');
                %                 UpdateTempDataTabInfo(app,info_temp);
                UpdateTempDataTabInfo(app,EM.identifier);
                return;
            end
            sizeData = size(app.Tab3_Origin);
            rowtemp = sizeData(1);
            coltemp = sizeData(2);
            app.DataSize_Row =  rowtemp;% 数据大小
            app.DataSize_Col = coltemp;
            app.EditField_4.Value = num2str(coltemp);
            app.EditField_2.Value = num2str(rowtemp);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            try
                winopen(app.EditField.Value);
                info_01 = '  打开成功';
                UpdateTempDataTabInfo(app,strcat(app.tempTxtFilePath,info_01));
            catch EM
                UpdateTempDataTabInfo(app,EM.identifier);
            end
        end

        % Callback function
        function Button_4Pushed(app, event)
            figure
            plot(linspace(1,10,10),linspace(1,10,10))
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % 绘制概览图
            info_temp = '绘制概览图';
            row_temp = str2num(app.EditField_2.Value);
            col_temp = str2num(app.EditField_4.Value);
            if isequal(row_temp,0) || isequal(col_temp,0)
                info_temp = strcat(info_temp,'失败,数据不足');
            else
                figure
                for i = 1:1:col_temp
                    subplot(col_temp,1,i)
                    plot(app.Tab3_Origin(:,i))
                    title(strcat(num2str(i),'列'));
                end
                info_temp = strcat(info_temp,'完成');
            end
            UpdateTempDataTabInfo(app,info_temp);
        end

        % Value changed function: egtimeencodercapheightEditField
        function egtimeencodercapheightEditFieldValueChanged(app, event)
            value = app.egtimeencodercapheightEditField.Value;
            % 解析列名字
            value = string(value);
            value = strrep(value,"[","");
            value = strrep(value,"]","");
            value = split(value,",");
            
            names = strings(0);
            for i=1:1:length(value)
                names(i) = value(i);
            end
            app.ColName = names;
            
            % 更新绘图标签
            try
                app.AxisX1Label.Text = 'Axis-' + names(1);
                app.AxisY1Label.Text = 'Axis-' + names(2);
                app.AxisY2Label.Text = 'Axis-' + names(3);
                app.AxisY3Label.Text = 'Axis-' + names(4);
                app.AxisY4Label.Text = 'Axis-' + names(5);
                app.AxisY5Label.Text = 'Axis-' + names(6);
                app.AxisY6Label.Text = 'Axis-' + names(7);
            catch
                
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if ~isempty(app.ColName)
                app.ColName = "";
                app.egtimeencodercapheightEditField.Value = "";
            end
        end

        % Button pushed function: Button_7
        function Button_4Pushed2(app, event)
            % 绘图程序
            checkStatus = [ app.SetToPlotCheckBox.Value,app.CheckBox_2.Value ,...
                app.CheckBox_3.Value ,...
                app.CheckBox_4.Value ,app.CheckBox_5.Value ,...
                app.CheckBox_6.Value ,app.CheckBox_7.Value ];
            
            if ~any(checkStatus)
                info_temp = '无选中数据,绘图失败';
            else
                % 统计非零位置
                try
                    NoZeros = zeros(0);
                    legends = strings(0);
                    Cnt = 0;
                    for i = 1:1:length(checkStatus)
                        if checkStatus(i) ~= 0
                            Cnt = Cnt + 1;
                            NoZeros(Cnt) = i;
                            legends(Cnt) = app.ColName(i);
                        end
                    end
                catch
                    
                end
                % figure
                try
                    figure
                    for i = 2:1:Cnt
                        temp = NoZeros(i);
                        plot(app.Tab3_Origin(:,1),app.Tab3_Origin(:,temp))
                        hold on
                    end
                    legend(legends(2:end));
                    grid minor
                    info_temp ='绘图完成';
                catch EM
                    info_temp = EM.identifier;
                end
                
            end
            UpdateTempDataTabInfo(app,info_temp);
            
        end

        % Close request function: MYFUNC
        function MYFUNCCloseRequest(app, event)
            delete(app)
            delete(timerfindall());
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % 重置绘图选项
            app.SetToPlotCheckBox.Value = 0;
            app.CheckBox_2.Value = 0;
            app.CheckBox_3.Value = 0;
            app.CheckBox_4.Value = 0;
            app.CheckBox_5.Value = 0;
            app.CheckBox_6.Value = 0;
            app.CheckBox_7.Value = 0;
            info_temp = '绘图选项重置...';
            UpdateTempDataTabInfo(app,info_temp);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            defaultFile = app.EditField.Value;
            if isequal(defaultFile,'C:\Users\Whtest\Desktop\temp.txt')
                [file,path] = uigetfile('C:\Users\Whtest\Desktop\*.txt');
            else
                [file,path] = uigetfile(defaultFile);
            end
            
            if isequal(file,0)
                info_temp = 'User selected Cancel...';
            else
                info_temp = strcat('User selected ', fullfile(path,file));
                app.EditField.Value = fullfile(path,file);
            end
            UpdateTempDataTabInfo(app,info_temp);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            %{
                清除文件选择信息
            %}
            
            app.EditField.Value = 'C:\Users\Whtest\Desktop\temp.txt';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MYFUNC = uifigure;
            app.MYFUNC.Name = 'MYFUNC';
            app.MYFUNC.CloseRequestFcn = createCallbackFcn(app, @MYFUNCCloseRequest, true);
            app.TabGroup = uitabgroup(app.MYFUNC);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.Button_2 = uibutton(app.ImportTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '载入数据';
            app.Label_2 = uilabel(app.ImportTab);
            app.Label_2.Text = '文件路径';
            app.EditField = uieditfield(app.ImportTab, 'text');
            app.EditField.Value = 'C:\Users\Whtest\Desktop\temp.txt';
            app.MYTEMPFUNCLabel = uilabel(app.ImportTab);
            app.MYTEMPFUNCLabel.Text = 'MY-TEMP-FUNC';
            app.Button_3 = uibutton(app.ImportTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '查看文件';
            app.Label_3 = uilabel(app.ImportTab);
            app.Label_3.Text = '载入数据结果：';
            app.Label_7 = uilabel(app.ImportTab);
            app.Label_7.Text = '设置数据名称';
            app.egtimeencodercapheightEditField = uieditfield(app.ImportTab, 'text');
            app.egtimeencodercapheightEditField.ValueChangedFcn = createCallbackFcn(app, @egtimeencodercapheightEditFieldValueChanged, true);
            app.egtimeencodercapheightLabel = uilabel(app.ImportTab);
            app.egtimeencodercapheightLabel.Text = 'e.g:[time,encoder,...,capheight]';
            app.Button_5 = uibutton(app.ImportTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '绘制概览图';
            app.Button_6 = uibutton(app.ImportTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '清空';
            app.Button_9 = uibutton(app.ImportTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '选择文件';
            app.Button_11 = uibutton(app.ImportTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '清除文件';
            app.Label_10 = uilabel(app.ImportTab);
            app.Label_10.Text = '行：0 ,列：0';
            app.Data1Tab = uitab(app.TabGroup);
            app.Data1Tab.Title = 'Data-1';
            app.Button_7 = uibutton(app.Data1Tab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed2, true);
            app.Button_7.Text = '绘图';
            app.Label_9 = uilabel(app.Data1Tab);
            app.Label_9.Text = '绘图设置';
            app.Button_8 = uibutton(app.Data1Tab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '重置绘图选项';
            app.AxisLabel = uilabel(app.Data1Tab);
            app.AxisLabel.Text = 'Axis';
            app.HandleFunctionLabel = uilabel(app.Data1Tab);
            app.HandleFunctionLabel.Text = 'Handle Function';
            app.XTextArea_2Label = uilabel(app.Data1Tab);
            app.XTextArea_2Label.Text = 'X';
            app.XTextArea_2 = uitextarea(app.Data1Tab);
            app.XTextArea_2.Value = {'y = a*x + b'};
            app.Y1TextAreaLabel = uilabel(app.Data1Tab);
            app.Y1TextAreaLabel.Text = 'Y1';
            app.Y1TextArea = uitextarea(app.Data1Tab);
            app.Y1TextArea.Value = {'y=x'};
            app.Y2TextAreaLabel = uilabel(app.Data1Tab);
            app.Y2TextAreaLabel.Text = 'Y2';
            app.Y2TextArea = uitextarea(app.Data1Tab);
            app.Y2TextArea.Value = {'y=x'};
            app.Y3TextAreaLabel = uilabel(app.Data1Tab);
            app.Y3TextAreaLabel.Text = 'Y3';
            app.Y3TextArea = uitextarea(app.Data1Tab);
            app.Y3TextArea.Value = {'y=x'};
            app.Y4TextAreaLabel = uilabel(app.Data1Tab);
            app.Y4TextAreaLabel.Text = 'Y4';
            app.Y4TextArea = uitextarea(app.Data1Tab);
            app.Y4TextArea.Value = {'y=x'};
            app.Y5TextAreaLabel = uilabel(app.Data1Tab);
            app.Y5TextAreaLabel.Text = 'Y5';
            app.Y5TextArea = uitextarea(app.Data1Tab);
            app.Y5TextArea.Value = {'y=x'};
            app.Y6TextAreaLabel = uilabel(app.Data1Tab);
            app.Y6TextAreaLabel.Text = 'Y6';
            app.Y6TextArea = uitextarea(app.Data1Tab);
            app.Y6TextArea.Value = {'y=x'};
            app.Y7TextAreaLabel = uilabel(app.Data1Tab);
            app.Y7TextAreaLabel.Text = 'Y7';
            app.Y7TextArea = uitextarea(app.Data1Tab);
            app.Y7TextArea.Value = {'y=x'};
            app.SetToPlotCheckBox = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox.Text = 'SetToPlot';
            app.SetToPlotCheckBox_2 = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox_2.Text = 'SetToPlot';
            app.SetToPlotCheckBox_3 = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox_3.Text = 'SetToPlot';
            app.SetToPlotCheckBox_4 = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox_4.Text = 'SetToPlot';
            app.SetToPlotCheckBox_5 = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox_5.Text = 'SetToPlot';
            app.SetToPlotCheckBox_6 = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox_6.Text = 'SetToPlot';
            app.SetToPlotCheckBox_7 = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox_7.Text = 'SetToPlot';
            app.SetToPlotCheckBox_8 = uicheckbox(app.Data1Tab);
            app.SetToPlotCheckBox_8.Text = 'SetToPlot';
            app.NothingLabel = uilabel(app.MYFUNC);
            app.NothingLabel.Text = 'Nothing';
            app.Label_8 = uilabel(app.MYFUNC);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataAnlysis_R1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MYFUNC)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MYFUNC)
        end
    end
end