classdef TickTock < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TickTockUIFigure  matlab.ui.Figure
        TICKTOCKLabel     matlab.ui.control.Label
        GMT8EditField     matlab.ui.control.EditField
        TRACKButton       matlab.ui.control.Button
        Lamp              matlab.ui.control.Lamp
        SysInfoLabel      matlab.ui.control.Label
        GMT8Label_2       matlab.ui.control.Label
        Label             matlab.ui.control.Label
        TimerButton       matlab.ui.control.StateButton
        UIAxes            matlab.ui.control.UIAxes
        Button            matlab.ui.control.Button
    end

    
    properties (Access = public)
        CurTime string % 控件时间显示
    end
    
    properties (Access = private)
        % 时间显示定时器
        Timer_01 = timer('StartDelay', 5, 'Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        
        % 时间统计
        StartTick = '';   % 计时器启动时间
        EndTick = '';     % 计时器结束时间
        ItemDuration = 0; % 持续时间 min
        ItemName char ;   % 事件
        ItemDetail char;  % 事件详情
        
        
        % 定时器,刷新显示
        Timer_02 = timer('Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate'); % Description
        
        % 定时器2，刷新显示
        Timer_03 = timer('Period',10,'TasksToExecute',Inf,'ExecutionMode', 'fixedRate');
        ExitSelection char; % 退出标志
        PreSysInfo char; % 上一条记录信息
    end
    
    methods (Access = private)
        
        function ShowTime_01(app)
            % 显示窗口二
            if true
                app.GMT8Label_2.FontName = 'Serif';
                app.GMT8Label_2.FontSize = 14;
                app.GMT8Label_2.FontWeight = 'bold';
                CurTime_02 = strcat(datestr(now,'yyyy/mm/dd'),'-',datestr(now,'hh:mm:ss'));
                app.GMT8Label_2.FontColor =  [0.26 0.26 0.26];
                app.GMT8EditField.Value = CurTime_02;
            end
        end
        
        function DrawDurationBar_01(app)
            temp = etime(datevec(datestr(datetime('now'),'yy/mm/dd  HH:MM:SS')),...
                datevec(app.StartTick));
            app.ItemDuration = int32(temp/1); % min
            %fprintf('%ds\n',app.ItemDuration);
            b_1 = barh(app.UIAxes,0.25,app.ItemDuration,0.45);
            % 设置柱状图
            b_1.FaceColor = [0 1 0];
            b_1.EdgeColor = [rand rand rand];
            b_1.LineWidth = 1.2;
        end
        
        %{
            Designe thoughts:
            1.Keep an item information by one line in log_01.txt,
            including item name,time,duration and so on;
        %}
        function KeepItemInfo(app)
            %{
                logpath选择需要摆脱文件夹路径限制
            %}
            logpath = 'D:\Codes\MatlabFiles\Blocks\TickTockFiles';
            logname = 'log.txt';
            fileID = fopen(strcat(logpath,'\',logname),'a+');
            if ~isequal(fileID,0)
                % 时间|事项|详情|持续时间
                StTemp = app.StartTick;
                EtTemp = app.EndTick;
                DutationtTemp = app.ItemDuration;
                ItemNameTemp =  app.ItemName;
                ItemDetailTemp = app.ItemDetail;
                app.PreSysInfo = strcat(ItemNameTemp,'|',...
                    ItemDetailTemp,'|',num2str(DutationtTemp));
                fprintf(fileID,'%s\t|\t%s\t|\t%s\t|\t%s\t|\t%d\n',StTemp...
                    ,EtTemp,ItemNameTemp,ItemDetailTemp,DutationtTemp);
                disp('file open succeed...')
            end
            fclose(fileID);
        end
        
        function selection = CloseReq(app)
            % get
            value = app.ExitSelection;
            selection = questdlg('Are you sure ?',...
                'Exit','Yes','No','No');
            % set
            app.ExitSelection = selection;
        end
        
        % 清空信息 & 更行显示info
        function ClearKeepInfo(app)
            app.StartTick = '';
            app.EndTick = '';
            app.ItemDuration = 0;
            app.ItemName = '';
            app.ItemDetail = '';
        end
        
        function UpdatePanle(app)
            if ~isequal(app.ItemDuration,0)
                app.TRACKButton.BackgroundColor = [0.1,0.5,0.8];
            else
                app.TRACKButton.BackgroundColor = [0.9,0.9,0.9];
            end
        end
        
        % 更新显示状体信息
        function UpdataSysInfo(app,info)
            if ischar(info)
                app.SysInfoLabel.Text = info;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            InfoStart = strcat('启动嘀嗒工具','');
            fprintf('%s\n',InfoStart);
            % 绘制背景图
            % app.TickTockUIFigure.ImageSource = 'Wallpaper_21.jpg';
            
            % 初始化窗口显示
            app.GMT8EditField.Value = "Initializing...";
            
            % 启动定时器1
            app.Timer_01.TimerFcn = @(~, ~) ShowTime_01(app);
            start(app.Timer_01);
            
            % 定时器2 绑定函数
            app.Timer_02.TimerFcn = @(~,~) DrawDurationBar_01(app);
            
            % 定时器3绑定函数
            app.Timer_03.TimerFcn = @(~,~) UpdatePanle(app);
            start(app.Timer_03);
            % 初始化时间
            app.StartTick = '';
            app.EndTick = '';
            % fprintf('%s,%s\n',app.StartTick,app.EndTick);
        end

        % Button pushed function: TRACKButton
        function TRACKButtonPushed(app, event)
            % 计时器记录中
            if app.TimerButton.Value
                sysInfo = '记录按钮不可用';
                UpdataSysInfo(app,sysInfo);
                %                 app.SysInfoLabel.Text = sysInfo;
            else
                sysInfo = '点击记录';
                app.SysInfoLabel.Text = sysInfo;
                if app.ItemDuration ~= 0
                    prompt_01 = {'ItemName','ItemDetail'};
                    title_01 = 'KEEP INFO';
                    dims_01 = [1 40];
                    answer_01 = inputdlg(prompt_01,title_01,dims_01);
                    if isempty(answer_01)
                        return;
                    end
                    ansPartOne = answer_01{1};
                    ansPartTwo = answer_01{2};
                    if isempty(ansPartOne) || isempty(ansPartTwo)
                        return;
                    else
                        app.ItemName = ansPartOne;
                        app.ItemDetail = ansPartTwo;
                    end
                    answer_02 = questdlg('Track the Info',...
                        'KEEP INFO','YES','NO','NO');
                    switch answer_02
                        case 'YES'
                            % 保存
                            KeepItemInfo(app);
                            ClearKeepInfo(app);
                            
                        case 'NO'
                            % nothing
                    end
                else
                    UpdataSysInfo(app,app.PreSysInfo);
                    %app.SysInfoLabel.Text = app.PreSysInfo;
                end
            end
            
        end

        % Close request function: TickTockUIFigure
        function TickTockUIFigureCloseRequest(app, event)
            % 关闭提醒
            selection = CloseReq(app);
            if isequal(selection,'Yes')
                % timer stop 导致回调出错
                if ~isempty(timerfind(app.Timer_01))
                    delete(app.Timer_01);
                end
                % 手动删除定时器
                delete(timerfind(app.Timer_02));
                delete(timerfind(app.Timer_03));
                delete(timerfindall);
                delete(app);
            else
                return;
            end
            
        end

        % Value changed function: TimerButton
        function TimerButtonValueChanged(app, event)
            %{
                触发式开关，可以作为状态改变，在状态改变之后做
                为初始化函数使用；
            %}
            % 初始化之后value = true;
            value = app.TimerButton.Value;
            state_01 = ["Start","Stop "];
            % 空闲状态
            if ~value
                % 启动计时器状态中
                app.TimerButton.Text = state_01(1);
                app.Lamp.Color = [0.64 0.08 0.1];
                
                app.EndTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                
                if ~isempty(timerfind(app.Timer_02))
                    stop(app.Timer_02);
                end
            else
                app.TimerButton.Text = state_01(2);
                app.Lamp.Color = [0 1 0];
                
                % 停止计时器状态中
                app.ItemDuration = 0;
                
                app.StartTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                start(app.Timer_02);
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            defaultPath = 'D:\Codes\MatlabFiles\Blocks\TickTockFiles\';
            logname = 'log.txt';
            fullLog = strcat(defaultPath,logname);
            try
                winopen(fullLog)
            catch
                disp('log open failed')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TickTockUIFigure = uifigure;
            app.TickTockUIFigure.Name = 'Tick-Tock';
            app.TickTockUIFigure.CloseRequestFcn = createCallbackFcn(app, @TickTockUIFigureCloseRequest, true);
            app.TICKTOCKLabel = uilabel(app.TickTockUIFigure);
            app.TICKTOCKLabel.Text = 'TICK TOCK';
            app.GMT8EditField = uieditfield(app.TickTockUIFigure, 'text');
            app.TRACKButton = uibutton(app.TickTockUIFigure, 'push');
            app.TRACKButton.ButtonPushedFcn = createCallbackFcn(app, @TRACKButtonPushed, true);
            app.TRACKButton.Text = 'TRACK';
            app.Lamp = uilamp(app.TickTockUIFigure);
            app.SysInfoLabel = uilabel(app.TickTockUIFigure);
            app.SysInfoLabel.Text = 'Sys-Info';
            app.GMT8Label_2 = uilabel(app.TickTockUIFigure);
            app.GMT8Label_2.Text = 'GMT+8';
            app.Label = uilabel(app.TickTockUIFigure);
            app.Label.Text = '';
            app.TimerButton = uibutton(app.TickTockUIFigure, 'state');
            app.TimerButton.ValueChangedFcn = createCallbackFcn(app, @TimerButtonValueChanged, true);
            app.TimerButton.Icon = 'power_button.png';
            app.TimerButton.Text = 'Timer';
            app.TimerButton.Value = true;
            app.UIAxes = uiaxes(app.TickTockUIFigure);
            app.UIAxes.XLim = [0 240];
            app.UIAxes.YLim = [0 0.5];
            app.UIAxes.XTick = [0 30 60 90 120 150 180 210 240];
            app.UIAxes.XTickLabel = {'0'; '30'; '60'; '90'; '120'; '150'; '180'; '210'; '240'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.Button = uibutton(app.TickTockUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '查看日志';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TickTock

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TickTockUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TickTockUIFigure)
        end
    end
end