classdef TickTock_BeauVer1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeautyVerOneUIFigure  matlab.ui.Figure
        SysInfoLabel          matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        Tick                  matlab.ui.container.Tab
        Button                matlab.ui.control.Button
        STARTLabel            matlab.ui.control.Label
        Button_3              matlab.ui.control.Button
        Gauge                 matlab.ui.control.LinearGauge
        ViewLogButton         matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        ADDLOGButton          matlab.ui.control.Button
        VIEWLOGLabel          matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        Label_5               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        GenReportButton       matlab.ui.control.Button
        Label_8               matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        TOCKTab               matlab.ui.container.Tab
        Label2                matlab.ui.control.Label
        PieceWordsButton      matlab.ui.control.Button
        StatsTab              matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        StatsButton           matlab.ui.control.Button
    end

    %  1,-200,1080,900 背景可用
    %
    properties (Access = private)
        % 更新时间柱
        Timer_01 = timer('StartDelay', 1, 'Period', 1,...
            'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');F
        Button_Down = false; % 按钮状态
        
        % 时间统计
        StartTick = '';   % 计时器启动时间
        EndTick = '';     % 计时器结束时间
        ItemDuration = 0; % 持续时间 min
        ItemName char ;   % 事件
        ItemDetail char;  % 事件详情
        
        % 更行系统显示时间
        Timer_02 = timer('StartDelay', 1, 'Period', 1,...
            'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        LogPath char;  % 日志路径
        LogFile char;  % 完整日志文件
        DatePicker;    % 日期选择
        PieceCnt ; % Description
    end
    
    methods (Access = private)
        function ImageBackGroundTab_01(app)
            try
                %                 imshow('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Wallpaper_97.jpg',...
                %                     'Parent',app.UIAxes_2);
            catch EM
                fprintf('error message:%s\n',EM.identifier);
            end
        end
        
        
        % 状态显示信息
        function UpdateSysInfo(app,info)
            if ischar(info)
                app.SysInfoLabel.Text = info;
            end
        end
        
        %状态标志，用于记录，记录按钮是否按下
        function InitStates(app)
            app.Button_Down = false;
        end
        
        % 时间柱
        function DrawDurationBar_01(app)
            temp = etime(datevec(datestr(datetime('now'),'yy/mm/dd  HH:MM:SS')),...
                datevec(app.StartTick));
            debugging = false;
            if debugging
                app.ItemDuration = int32(temp/1); % min
            else
                app.ItemDuration = int32(temp/60); % min
            end
            app.Gauge.Value = app.ItemDuration;
        end
        
        % 系统时间
        function SystemTimeUpdate(app)
            app.Label.Text = datestr(datetime('now'),'HH:MM:SS');
        end
        
        % 记录日志
        function KeepItemInfo(app)
            %{
                logpath选择需要摆脱文件夹路径限制
            %}
            fileID = fopen(app.LogFile,'a+');
            if ~isequal(fileID,0)
                % 时间|事项|详情|持续时间
                StTemp = app.StartTick;
                EtTemp = app.EndTick;
                DutationtTemp = app.ItemDuration;
                ItemNameTemp =  app.ItemName;
                ItemDetailTemp = app.ItemDetail;
                
                fprintf(fileID,'%s\t|\t%s\t|\t%s\t|\t%s\t|\t%d\n',StTemp...
                    ,EtTemp,ItemNameTemp,ItemDetailTemp,DutationtTemp);
                disp('file open succeed...')
            end
            fclose(fileID);
        end
        
        % 初始化日志文件
        function  InitLogFile(app)
            %{
                两种处理方式：
                一：相对路径方式（最优）
                二：固定路径方式（先用）
            %}
            app.LogPath = 'D:\Codes\MatlabFiles\Blocks\TickTockFiles\Logs';
            [status,msg] = mkdir(app.LogPath);
            UpdateSysInfo(app,msg);
            % 新建日志文件
            try
                curlog = datestr(datetime('now'),'yyyy-mm-dd');
                curlog = strcat(curlog,'.txt');
                exitstatus = 0;
                dirtemp = dir(app.LogPath);
                % 当前文件夹中是否包含当天日志文件
                for i = 3:1:length(dirtemp)
                    temp = dirtemp(i).name;
                    if strcmp(temp,curlog)
                        exitstatus = 1;
                        break;
                    end
                end
                
                if exitstatus == 0
                    fileID = fopen(fullfile('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Logs',...
                        curlog),'w');
                    fclose(fileID);
                end
                app.LogFile = fullfile('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Logs',...
                    curlog);
            catch EM
                EM.identifier
            end
            info_temp = '初始化完成....';
            UpdateSysInfo(app,info_temp);
        end
        
        % 手动添加单条信息
        %{
            1.时间段
            2.事项
        %}
        function results = AddPieceLog(app)
            %  创建对话框
            prompt = {'Start Time','Finish Time','Item','Detail'};
            dlgtitle = 'Add a piece of Time log';
            dims = [1 60];
            dateformat = 'yy/mm/dd HH:MM';
            defaultTime = datestr(datetime('now'),dateformat);
            definput = {defaultTime,defaultTime,'',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            if isempty(answer)
                info_temp = 'cancle';
                results = info_temp;
            else
                startTemp = answer{1};
                starttemp = datestr(startTemp,'HH:MM');
                finishTemp = answer{2};
                finishtemp = datestr(finishTemp,'HH:MM');
                gaptemp = minutes(duration(finishtemp,'InputFormat','hh:mm')...
                    - duration(starttemp,'InputFormat','hh:mm'));
                LogPathus = app.LogPath ;
                filenameTemp = strcat('20', datestr(answer{2},'yy-mm-dd'));
                filetxt = fopen(strcat(fullfile(LogPathus,filenameTemp),'.txt'),'a+');
                % 校验文件打开是否成功
                if filetxt ~= 0
                    info_temp = strcat(strcat(answer{1},':00'),'|',strcat(answer{2},':00'),'|',answer{3}...
                        ,'|',answer{4},'|',gaptemp);
                    fprintf(filetxt,'%s\t|\t%s\t|\t%s\t|\t%s\t|\t%d\n',strcat(answer{1},':00')...
                        ,strcat(answer{2},':00'),answer{3},answer{4},gaptemp);
                    fclose(filetxt);
                end
                results = info_temp;
            end
        end
        
        
        %{
            通过文件添加日志
        %}
        function results = AddFileLog(app)
            % 导入文件
            fileopenFlag = true;
            [file,path] = uigetfile('C:\Users\10520\Desktop\*.txt');
            try
                opt = detectImportOptions(fullfile(path,file));
                opt.VariableNames = {'starttime','endtime','item','detail'};
                opt.Delimiter = ',';
                opt.Encoding = 'UTF-8';
                fileIn = readtable(fullfile(path,file),opt);
                
            catch EM
                % 错误处理
                results = strcat('文件打开失败:',EM.identifier);
                fileopenFlag = false;
            end
            
            % 处理文件
            if fileopenFlag
                LogPathus = app.LogPath ;
                %                 LogPathus = 'C:\Users\10520\Desktop';
                NullIndex = 1;
                for i = 1:1:length(fileIn.starttime)
                    filetxt = strcat(LogPathus,'\',datestr(fileIn.endtime(i),'yyyy-mm-dd'),'.txt');
                    try
                        fileId = fopen(filetxt,'a+');
                        startTemp = datestr(fileIn.starttime(i),'yy/mm/dd HH:MM:SS');
                        finishTemp = datestr(fileIn.endtime(i),'yy/mm/dd HH:MM:SS');
                        startDay =  datestr(fileIn.starttime(i),'yy/mm/dd');
                        endDay = datestr(fileIn.endtime(i),'yy/mm/dd');
                        
                        gapTemp = 0;
                        formatTemp = 'hh:mm';
                        if isequal(startDay,endDay)
                            gapTemp = minutes( duration(datestr(fileIn.endtime(i),'HH:MM'),'InputFormat',formatTemp)...
                                - duration(datestr(fileIn.starttime(i),'HH:MM'),'InputFormat',formatTemp));
                        else
                            gapTemp1 = minutes( duration('23:59','InputFormat',formatTemp)...
                                - duration(datestr(fileIn.starttime(1),'HH:MM'),'InputFormat',formatTemp));
                            gapTemp2 = minutes( duration(datestr(fileIn.endtime(1),'HH:MM'),'InputFormat',formatTemp)...
                                - duration('00:01','InputFormat',formatTemp));
                            gapTemp = gapTemp1 + gapTemp2;
                        end
                        
                        % 异常时间
                        if gapTemp < 0
                            gapTemp = 0;
                        end
                        itemtemp = '';
                        detailtemp = '';
                        
                        itemtemp = fileIn.item{i};
                        detailtemp = fileIn.detail{i};
                        % item缺失则表示同上条
                        if isempty(itemtemp)
                            itemtemp = fileIn.item{NullIndex};
                            detailtemp = fileIn.detail{NullIndex};
                        else
                            NullIndex = i;
                        end
                        % detail缺失则将item复制
                        if isempty(detailtemp)
                            detailtemp = itemtemp;
                        end
                        strrep(itemtemp,' ','');
                        strrep(detailtemp,' ','');
                        fprintf(fileId,'%s\t|\t%s\t|\t%s\t|\t%s\t|\t%d\n',startTemp,finishTemp,itemtemp,detailtemp,gapTemp);
                        fclose(fileId);
                        results = '日志导入成功';
                    catch EM
                        results = strcat('写日志失败',EM.Identifier);
                    end
                end
            end
        end
        
        %{
             生成饼状图报表
        %}
        function results = Gen_DayReport(app)
            nowfile = datestr(app.DatePicker.Value,'yyyy-mm-dd');
            nowfile = strcat(nowfile,'.txt');
            AllFileTemp = dir(app.LogPath);
            file_temp = "";
            for i = 1:1:length(AllFileTemp)
                file_temp = strcat(AllFileTemp(i).name,'|',file_temp);
            end
            if contains(file_temp,nowfile)
                fullfile = strcat(app.LogPath,'\',nowfile);
                tableTemp = readtable(fullfile,'Delimiter','|');
                detailArrayTemp = strings(0);
                detailArrayTimeTemp = zeros(0);
                detailTemp = "";
                detailCntTemp = 1;
                for i = 1:1:length(tableTemp.Var1)
                    % 补全表格
                    if isempty(tableTemp.Var4(i)) || isempty(tableTemp.Var5(i))&& i > 1
                        tableTemp.Var4(i) = tableTemp.Var4(i-1);
                        tableTemp.Var5(i) = tableTemp.Var5(i-1);
                    end
                    % dictionary
                    if ~contains(detailTemp,char(tableTemp.Var4(i)))
                        detailTemp = strcat(detailTemp,tableTemp.Var4(i));
                        detailArrayTemp(detailCntTemp) = tableTemp.Var4(i);
                        detailArrayTimeTemp(detailCntTemp) = 0;
                        detailCntTemp = detailCntTemp + 1;
                    end
                end
                clearvars i;
                %dictionary
                for i = 1:1:length(tableTemp.Var1)
                    for j = 1:1:length(detailArrayTemp)
                        if detailArrayTemp(j) == tableTemp.Var4(i)
                            postemp = j;
                            break;
                        end
                    end
                    detailArrayTimeTemp(postemp) = detailArrayTimeTemp(postemp) + ...
                        tableTemp.Var5(i);
                end
                if length(detailArrayTemp)==1
                    results = 'Too less data,gen report failed';
                    return;
                end
                tempCell = cell(size(detailArrayTemp));
                for j = 1:1:length(detailArrayTemp)
                    tempStr = strcat(detailArrayTemp(j),'|',num2str(detailArrayTimeTemp(j)));
                    tempStr = char(tempStr);
                    tempCell{j} = tempStr;
                end
                cla(app.UIAxes_2,'reset');
                app.UIAxes_2.XTick = [];
                app.UIAxes_2.YTickLabel = [];
                app.UIAxes_2.XTickLabel = [];
                app.UIAxes_2.YTick = [];
                cla(app.UIAxes);
                pie(app.UIAxes,detailArrayTimeTemp,detailArrayTemp)
                bar(app.UIAxes_2,categorical(tempCell),detailArrayTimeTemp)
                
                results = strcat(nowfile,'日志生成');
            else
                results = '无效日期';
            end
        end
        
        function results = DaytimeBar(app)
            results = '生成每日统计信息';
            % 更新按钮标签
            if isequal(app.StatsButton.Text,'Stats')
                app.StatsButton.Text = 'Cover';
            else
                app.StatsButton.Text = 'Stats';
            end
        end
        
        %{
            检查日志文件是否需要改变
        %}
        function results = CheckLogFile(app)
            results = false;
            curlog = datestr(datetime('now'),'yyyy-mm-dd');
            curlog = fullfile('D:\Codes\MatlabFiles\Blocks\TickTockFiles\Logs\',...
                strcat(curlog,'.txt'));
            if ~isequal(curlog,app.LogFile)
                app.LogFile = curlog;
                results = true;
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             clc;
            %             disp('tick tock beauty version')
            ImageBackGroundTab_01(app)
            % 启动时间柱
            app.Timer_01.TimerFcn = @(~, ~) DrawDurationBar_01(app);
            
            % 启动系统时间显示
            app.Timer_02.TimerFcn = @(~,~)SystemTimeUpdate(app);
            start(app.Timer_02);
            InitLogFile(app);
            
            % 初始化时间选择框
            app.DatePicker = uidatepicker(app.Tick);
            %DatePicker.InnerPosition = [10,146,71,30];
            app.DatePicker.Position = [14,170,96,30];
            app.DatePicker.Value = datetime('now');
            
            % 初始化pieceword 计数器
            app.PieceCnt = 0;
            
        end

        % Close request function: BeautyVerOneUIFigure
        function BeautyVerOneUIFigureCloseRequest(app, event)
            % 退出提示
            quitAns = questdlg('Are U Sure Exit ?','Exit','Yes','No','No');
            switch quitAns
                case 'Yes'
                    delete(app)
                    delete(timerfindall())
                otherwise
                    % do nothing
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
            if isequal(app.STARTLabel.Text,'STOP')
                UpdateSysInfo(app,'计时中，按钮不可用')
            else
                if app.ItemDuration < 1
                    UpdateSysInfo(app,'记录时间 < 1min，记录按钮不可用');
                    % 清除信息
                    app.ItemName = '';
                    app.ItemDetail = '';
                    app.ItemDuration = 0;
                    app.Gauge.Value = 0;
                else
                    prompt_01 = {'ItemName','ItemDetail'};
                    title_01 = 'KEEP      INFO';
                    dims_01 = [1 40];
                    answer_01 = inputdlg(prompt_01,title_01,dims_01);
                    if ~isempty(answer_01)
                        ansPartOne = answer_01{1};
                        ansPartTwo = answer_01{2};
                        if isempty(ansPartOne) || isempty(ansPartTwo)
                            return;
                        else
                            app.ItemName = ansPartOne;
                            app.ItemDetail = ansPartTwo;
                        end
                        DutationtTemp = app.ItemDuration;
                        ItemNameTemp =  app.ItemName;
                        ItemDetailTemp = app.ItemDetail;
                        answer_02 = questdlg(strcat(ItemNameTemp,'|',...
                            ItemDetailTemp,'|',num2str(DutationtTemp)),...
                            'Track Info','YES','NO','NO');
                        
                        if isequal(answer_02,'YES')
                            KeepItemInfo(app);
                            
                            info_temp = strcat(ItemNameTemp,'|',...
                                ItemDetailTemp,'|',num2str(DutationtTemp));
                            UpdateSysInfo(app,info_temp);
                            
                            % 清除信息
                            app.ItemName = '';
                            app.ItemDetail = '';
                            app.ItemDuration = 0;
                            app.Gauge.Value = 0;
                        end
                    end
                    
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.Button_Down;
            State = {'START','STOP'};
            if value
                % 启动计时器状态中
                app.STARTLabel.Text = State{1};
                app.Button.BackgroundColor = [0.47,0.67,0.19];
                app.EndTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                
                if ~isempty(timerfind(app.Timer_01))
                    stop(app.Timer_01);
                end
                info_temp = '停止中......';
            else
                app.STARTLabel.Text = State{2};
                app.Button.BackgroundColor = [0.64,0.08,0.18];
                
                % 停止计时器状态中
                app.ItemDuration = 0;
                app.StartTick = datestr(datetime('now'),'yy/mm/dd  HH:MM:SS');
                start(app.Timer_01);
                info_temp = '计时中......';
            end
            UpdateSysInfo(app,info_temp);
            app.Button_Down = ~value;
            
        end

        % Button pushed function: ViewLogButton
        function Button_3Pushed(app, event)
            info_temp = 'View-Log';
            nowfile = datestr(app.DatePicker.Value,'yyyy-mm-dd');
            nowfile = strcat(nowfile,'.txt');
            AllFileTemp = dir(app.LogPath);
            file_temp = "";
            for i = 1:1:length(AllFileTemp)
                file_temp = strcat(AllFileTemp(i).name,'|',file_temp);
            end
            if contains(file_temp,nowfile)
                try
                    winopen(strcat(app.LogPath,'\',nowfile));
                    info_temp = strcat(info_temp,app.LogFile);
                catch EM
                    info_temp =  EM.identifier;
                end
            else
                info_temp = strcat(nowfile,',No such Log File...');
            end
            UpdateSysInfo(app,info_temp);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if isequal(app.STARTLabel.Text,'STOP')
                info_temp = '计时中，清除Track信息失败';
            else
                % 清除信息
                app.ItemName = '';
                app.ItemDetail = '';
                app.ItemDuration = 0;
                app.Gauge.Value = 0;
                cla(app.UIAxes);
                cla(app.UIAxes_2,'reset');
                app.UIAxes_2.XTick = [];
                app.UIAxes_2.YTickLabel = [];
                app.UIAxes_2.XTickLabel = [];
                app.UIAxes_2.YTick = [];
                info_temp = '清除计时信息完成';
            end
            UpdateSysInfo(app,info_temp);
        end

        % Button pushed function: ADDLOGButton
        function ADDLOGButtonPushed(app, event)
            % 将所有外部日志信息规范化之后按照日志日期进行记录
            inputModel = questdlg('Add Log Model:','Add Log',...
                'Sigle Piece','By File','Cancel','Cancel');
            %{
                1.模式选择：单条记录信息、通过文件添加
                %}
                switch inputModel
                    case 'Sigle Piece'
                        info_temp = inputModel;
                        results = AddPieceLog(app);
                        info_temp = strcat(info_temp,results);
                    case 'By File'
                        info_temp = inputModel;
                        results = AddFileLog(app);
                        info_temp = strcat(info_temp,results);
                    case 'Cancle'
                        info_temp = strcat(inputModel , 'd add log...');
                end
                
                UpdateSysInfo(app,info_temp);
        end

        % Callback function
        function GenReportButtonValueChanged(app, event)
            
            
        end

        % Button pushed function: GenReportButton
        function GenReportButtonPushed(app, event)
            Gen_Ans = questdlg('生成报表','Report Gen ?','Yes','No','No');
            switch Gen_Ans
                case 'Yes'
                    results = Gen_DayReport(app);
                    UpdateSysInfo(app,results);
                otherwise
                    
            end
        end

        % Button pushed function: PieceWordsButton
        function PieceWordsButtonPushed(app, event)
            %{
                获取每日一句
            %}
            app.PieceCnt = app.PieceCnt + 1;
            if app.PieceCnt > 1000
                app.PieceCnt = 0;
            end
            default_asn = {'I would wait forever';'我愿永远守候着你';'exalted in the scene';'为你每一次露面欣喜';...
                'As long as I am with you';'只要有你的陪伴';'my heart continues to beat';'心跳随你而律动'};
            info_temp = '更行每日一条';
            try
                % if mod(str2double(datestr(datetime('now'),'SS')),2) == 0
                if  mod(app.PieceCnt,2) == 0
                    url_daily = 'http://www.dailyenglishquote.com/';
                else
                    url_daily = 'http://www.dailyenglishquote.c/';
                end
                
                options = weboptions('RequestMethod','get','CharacterEncoding','utf-8',...
                    'Timeout',20);
                strf = webread(url_daily,options);
                % [1]获取所有<p> </p>
                strf_3 = regexp(strf,'<(p).*?>.*?</\1>','match');
                if ~isempty(strf_3) && length(strf_3) > 4
                    head = randi(int32((length(strf_3) - 4))/2 - 1);
                    %                     strf_4 = strf_3(head*2+1:head*2 + 2);
                    strf_4 = strf_3(1:2);
                    strf_4 = regexprep(strf_4,'&#\d{4};','');
                    en_char = regexp(strf_4{1},'(?<=(<span .*?>)|(<strong .*?>))(.*?)(?=(</span>)|(</strong>))','match');
                    en_char = char(en_char);
                    zh_char = regexp(strf_4{2},'((?<=<p>)|(?<=<br>))(.*?)((?=</p>)|(?=</br>)|(?=<br />))','match');
                    zh_char = char(zh_char);
                    %{
                        截断的方式实现
                    %}
                    % 中文字符截断
                    cutEn = {};
                    cutZn = {};
                    if length(char(zh_char)) > 22
                        cntLen = int32(length(zh_char)/22);
                        cutZn = cell(cntLen+1,1);
                        for i = 1:1:cntLen+1
                            if i*22 < length(zh_char)
                                cutZn{i,1} =  zh_char(22*(i-1)+1:22*i);
                            else
                                cutZn{i,1} =  zh_char(22*(i-1)+1:end);
                            end
                        end
                    end
                    % 英文截断
                    en_words = regexp(en_char,' ','split');
                    cutEn = {};
                    cntCutEn = 0;
                    lineCnt = 0;
                    lineLen = 48;
                    enTemp = '';
                    cnthead = 1;
                    for i = 1:1:length(en_words)
                        %     enTemp = strcat(enTemp,' ',en_words{i});
                        enTemp = strjoin(en_words(cnthead:i));
                        if lineCnt >= lineLen
                            cnthead = i;
                            cntCutEn = cntCutEn + 1;
                            cutEn{cntCutEn} = enTemp;
                            enTemp = '';
                            lineCnt = 0;
                        else
                            lineCnt = max(size(en_words{i})) + lineCnt + 1;
                        end
                    end
                    
                    if isempty(cutEn)
                        EnTemp = char(en_char);
                    else
                        EnTemp = cutEn;
                    end
                    
                    if isempty(cutZn)
                        ZnTemp = char(zn_char);
                    else
                        ZnTemp = cutZn;
                    end
                    asn = {};
                    cnt_01 = 1;
                    for i = 1:1:max(size(EnTemp))
                        asn(cnt_01) = EnTemp(i);
                        cnt_01 = cnt_01 + 1;
                    end
                    
                    % 分隔符
                    for i = 1:1:2
                        asn(cnt_01) = {''};
                        cnt_01 = cnt_01 + 1;
                    end
                    for j = 1:1:length(ZnTemp)
                        asn(cnt_01) = ZnTemp(j);
                        cnt_01 = cnt_01 + 1;
                    end
                    if isequal(head,0)
                        app.Label2.FontColor = [rand,rand,rand];
                        app.Label2.FontSize = 18;
                        app.Label2.Text = asn;
                    else
                        app.Label2.FontColor = [rand,rand,rand];
                        app.Label2.FontSize = 18;
                        app.Label2.Text = asn;
                    end
                end
                info_temp = strcat(info_temp,'完成');
            catch EM
                info_temp = strcat(info_temp,EM.identifier);
                app.Label2.Text = default_asn;
                app.Label2.FontSize = 24;
                app.Label2.FontColor = [0.00,0.45,0.74];
                
            end
            UpdateSysInfo(app,info_temp);
        end

        % Button pushed function: StatsButton
        function StatsButtonPushed(app, event)
            %{
                day time bar
            %}
            info_temp = DaytimeBar(app);
            UpdateSysInfo(app,info_temp);
            % ss = CheckLogFile(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeautyVerOneUIFigure = uifigure;
            app.BeautyVerOneUIFigure.Name = 'BeautyVerOne';
            app.BeautyVerOneUIFigure.CloseRequestFcn = createCallbackFcn(app, @BeautyVerOneUIFigureCloseRequest, true);
            app.SysInfoLabel = uilabel(app.BeautyVerOneUIFigure);
            app.SysInfoLabel.Text = 'SysInfo';
            app.Label = uilabel(app.BeautyVerOneUIFigure);
            app.TabGroup = uitabgroup(app.BeautyVerOneUIFigure);
            app.Tick = uitab(app.TabGroup);
            app.Tick.Title = 'TICK';
            app.Button = uibutton(app.Tick, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'power_button.png';
            app.Button.Text = '';
            app.STARTLabel = uilabel(app.Tick);
            app.STARTLabel.Text = 'START';
            app.Button_3 = uibutton(app.Tick, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Icon = 'record.png';
            app.Button_3.Text = '';
            app.Gauge = uigauge(app.Tick, 'linear');
            app.Gauge.Limits = [0 239];
            app.Gauge.MinorTicks = [];
            app.ViewLogButton = uibutton(app.Tick, 'push');
            app.ViewLogButton.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.ViewLogButton.Text = 'View-Log';
            app.Button_4 = uibutton(app.Tick, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'clear.png';
            app.Button_4.Text = '';
            app.ADDLOGButton = uibutton(app.Tick, 'push');
            app.ADDLOGButton.ButtonPushedFcn = createCallbackFcn(app, @ADDLOGButtonPushed, true);
            app.ADDLOGButton.Text = 'ADD-LOG';
            app.VIEWLOGLabel = uilabel(app.Tick);
            app.VIEWLOGLabel.Text = 'VIEW-LOG';
            app.UIAxes = uiaxes(app.Tick);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.UIAxes_2 = uiaxes(app.Tick);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = {'[ ]'};
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = {'[ ]'};
            app.Label_5 = uilabel(app.Tick);
            app.Label_5.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_6 = uilabel(app.Tick);
            app.Label_6.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.GenReportButton = uibutton(app.Tick, 'push');
            app.GenReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenReportButtonPushed, true);
            app.GenReportButton.Text = 'Gen-Report';
            app.Label_8 = uilabel(app.Tick);
            app.Label_8.Text = '————————————————————————————————————————————————————';
            app.Label_9 = uilabel(app.Tick);
            app.Label_9.Text = '————————————————————————————————————————————————————';
            app.TOCKTab = uitab(app.TabGroup);
            app.TOCKTab.Title = 'TOCK';
            app.Label2 = uilabel(app.TOCKTab);
            app.Label2.Text = '';
            app.PieceWordsButton = uibutton(app.TOCKTab, 'push');
            app.PieceWordsButton.ButtonPushedFcn = createCallbackFcn(app, @PieceWordsButtonPushed, true);
            app.PieceWordsButton.Text = 'Piece Words';
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.UIAxes2 = uiaxes(app.StatsTab);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = {'[ ]'};
            app.UIAxes2.YTick = [];
            app.StatsButton = uibutton(app.StatsTab, 'push');
            app.StatsButton.ButtonPushedFcn = createCallbackFcn(app, @StatsButtonPushed, true);
            app.StatsButton.Text = 'Stats';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TickTock_BeauVer1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeautyVerOneUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeautyVerOneUIFigure)
        end
    end
end